classdef SatelliteOrbitApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SettimerangeButtonGroup        matlab.ui.container.ButtonGroup
        SecondsButton                  matlab.ui.control.RadioButton
        PlotGroundTrackButton          matlab.ui.control.Button
        Spinner                        matlab.ui.control.Spinner
        CyclesButton                   matlab.ui.control.RadioButton
        GroundTrackPanel               matlab.ui.container.Panel
        madebyYoonjoongKimLabel        matlab.ui.control.Label
        SatelliteOrbitTrackerLabel     matlab.ui.control.Label
        TrajectoryontheOibitalPlanePanel  matlab.ui.container.Panel
        LoadTLEButton                  matlab.ui.control.Button
        DrawSequenceLinkButton         matlab.ui.control.Button
        SequenceEditField              matlab.ui.control.EditField
        Label_2                        matlab.ui.control.Label
        DeleteLinkButton               matlab.ui.control.Button
        DrawLinkButton                 matlab.ui.control.Button
        LinkSatBDropdown               matlab.ui.control.DropDown
        LinkSatBDropdownDropDownLabel  matlab.ui.control.Label
        LinkSatADropdown               matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        SatellitesPositionattheepochtimePanel  matlab.ui.container.Panel
        UIAxes2                        matlab.ui.control.UIAxes
        EnterTLEdataPanel              matlab.ui.container.Panel
        DelButton                      matlab.ui.control.Button
        AddSatelliteButton             matlab.ui.control.Button
        SatelliteDropdown              matlab.ui.control.DropDown
        SatelliteLabel                 matlab.ui.control.Label
        PlotOrbitButton                matlab.ui.control.Button
        UploadDataButton               matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        MeanmotionEditField            matlab.ui.control.NumericEditField
        MeanmotionEditFieldLabel       matlab.ui.control.Label
        MeananomalyEditField           matlab.ui.control.NumericEditField
        MeananomalyEditFieldLabel      matlab.ui.control.Label
        ArgumentofperigeeEditField     matlab.ui.control.NumericEditField
        ArgumentofperigeeEditFieldLabel  matlab.ui.control.Label
        EccentricityEditField          matlab.ui.control.NumericEditField
        EccentricityEditFieldLabel     matlab.ui.control.Label
        RAANEditField                  matlab.ui.control.NumericEditField
        RAANEditFieldLabel             matlab.ui.control.Label
        InclinationEditField           matlab.ui.control.NumericEditField
        InclinationEditFieldLabel      matlab.ui.control.Label
        EpochtimeEditField             matlab.ui.control.NumericEditField
        EpochtimeEditFieldLabel        matlab.ui.control.Label
    end

    
    methods (Access = public)
       
        % 这是一个公共接口，专门给外部文件调用
        function triggerInternalActions(app)
            % 因为这个函数在 App 内部，所以它有权调用私有的按钮函数
            app.UploadDataButtonPushed([]);
            app.PlotOrbitButtonPushed([]);
        end
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadDataButton
        function UploadDataButtonPushed(app, event)
            %%% Constants
            global mu D2R Re w_e
            G = 6.67384e-11;      % gravitational constant
            Me = 5.9742e24;       % Mass of Earth
            Re = 6371e+3;
            mu = G*Me;
            D2R = pi/180;
            w_e = 360/(24*60*60); % Angular Velocity of Earth [deg/s]
            
            %%% get Orbital Elements
            global te i RAAN e M n w epoch_time OE Period
            te = app.EpochtimeEditField.Value;
            i = app.InclinationEditField.Value;
            RAAN = app.RAANEditField.Value;            
            e = app.EccentricityEditField.Value;
            M = app.MeananomalyEditField.Value;            
            n = app.MeanmotionEditField.Value;
            w = app.ArgumentofperigeeEditField.Value;
            
            OE = getOE_app(te,i,RAAN,e,w,M,n);
            a = OE(1);
            Period = OE(7);
            epoch_time = OE(8);
            
            %%% display Orbital Elements
            cellArrayText{1} = sprintf('Semimajor Axis  a = \n %.2f [m]\n\n', a);
            cellArrayText{2} = sprintf('Eccentricity  e = \n %.2f \n\n', e);
            cellArrayText{3} = sprintf('Inclination  i = \n %.2f [deg]\n\n', i);
            cellArrayText{4} = sprintf('Right Ascenction of the Ascending Node  RAAN = \n %.2f [deg]\n\n', RAAN);
            cellArrayText{5} = sprintf('Argument of perige  w = \n %.2f [deg]\n\n', w);
            cellArrayText{6} = sprintf('Mean anomaly  M0 = \n %.2f [deg]\n\n', M);
            cellArrayText{7} = sprintf('Period  T = \n %.2f [s]\n', Period);
            app.TextArea.Value = cellArrayText;

        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            global trackTime Period
            time = app.Spinner.Value;
            
            if (app.CyclesButton.Value)
                trackTime = time * Period;
            elseif (app.SecondsButton.Value)
                trackTime = time;
            end
            
        end

        % Button pushed function: PlotOrbitButton
        function PlotOrbitButtonPushed(app, event)


            % 全局变量初始化
            global Re SatellitesCache SatellitesHandles
            global GlobalFrame
            GlobalFrame=1;
            global LinkTable
            if isempty(LinkTable)
                LinkTable = struct('satA',{},'satB',{},'handle',{});
            end
            global SatelliteList
            if isempty(SatelliteList)
                SatelliteList = {};
            end
            

            Re = 6371e3;
            if isempty(SatellitesCache), SatellitesCache = struct(); end
            if isempty(SatellitesHandles), SatellitesHandles = struct(); end
            global LinkTable
            LinkTable = struct('satA',{},'satB',{},'handle',{});


            % 读取参数
            selectedSatellite = app.SatelliteDropdown.Value;
            key = matlab.lang.makeValidName(selectedSatellite);

            global te i RAAN e M n w epoch_time OE Period
            te = app.EpochtimeEditField.Value;
            i = app.InclinationEditField.Value;
            RAAN = app.RAANEditField.Value;
            e = app.EccentricityEditField.Value;
            M = app.MeananomalyEditField.Value;
            n = app.MeanmotionEditField.Value;
            w = app.ArgumentofperigeeEditField.Value;

                        % === 统一世界时钟 ===
            
            % 1. 设定统一的仿真参数（所有卫星都一样）
            % 这里的 t_start = 0 代表我们从“现在”开始仿真
            t = 0; 
            
            % 2. 设定步长 dt (秒)
            % 建议设为 60秒。如果设为1秒，计算量太大，电脑会卡，且动画太慢。
            dt = 60; 
            
            % 3. 设定总时长 (秒)
            % 这里设定为 24 小时 (86400秒)。这样无论卫星周期多少，都跑满一天。
            TotalDuration = 24 * 60 * 60; 
            
            % 计算总步数 (24小时 / 60秒 = 1440 个点)
            num_steps = floor(TotalDuration / dt);

            % 4. 预分配内存（给变量占座，好习惯）
            x = zeros(num_steps, 1);
            y = zeros(num_steps, 1);
            z = zeros(num_steps, 1);
            
            % 计算初始位置（仅用于显示）
            [x_curr, y_curr, z_curr] = cal_position_app(t, OE);

            % 5. 开始循环计算
            % 注意：这里不再用 2*Period，而是用固定的 num_steps
            for j = 1 : num_steps
                t = t + dt; % 时间向前推进一步
                
                % 计算地球扁率对轨道的影响 (J2摄动)
                [OE(j+1,:)] = oblatenessEffects(OE(j,:));
                
                % 计算当前时刻的位置
                % 注意：因为我们不需要极坐标画图了，r 和 nu 可以省略，或者保留备用
                [~, ~] = cal_Orbit_app(t, OE(j+1,:)); 
                [x(j,1), y(j,1), z(j,1)] = cal_position_app(t, OE(j+1,:));
            end
            
            % 6. 【关键】删除了所有的 circshift 代码
            % 这样所有卫星的 x(1) 都代表 t=60秒 时的位置，完全同步。


            % 缓存轨迹
            SatellitesCache.(key) = struct('x', x, 'y', y, 'z', z, 'len', length(x));
            % === 注册卫星编号（只注册一次）===
            if ~ismember(key, SatelliteList)
                SatelliteList{end+1} = key;
            end

            % 绘制地球
            ax = app.UIAxes2;
            hold(ax, 'on');
            axis(ax, 'equal');
            xlabel(ax, 'X'); ylabel(ax, 'Y'); zlabel(ax, 'Z');
            if isempty(findobj(ax, 'Type', 'Surface'))
                load topo topo;
                [xs, ys, zs] = sphere(80);
                s = surface(ax, xs*Re, ys*Re, zs*Re);
                s.FaceColor = 'texturemap'; s.CData = topo; s.EdgeColor = 'none';
            end

            % 创建卫星对象
            SatellitesHandles.(key).orbitLine = plot3(ax, NaN, NaN, NaN, 'LineWidth', 1.2);
            SatellitesHandles.(key).satDot = plot3(ax, x(1), y(1), z(1), 'o', ...
                'MarkerSize', 8, 'MarkerFaceColor', 'r', 'MarkerEdgeColor', 'r');
            SatellitesHandles.(key).coveragePatch = [];

            % 启动动画
                        % === 第二步修改：安全的动画启动 ===

            % 1. 先检查这颗卫星是不是已经在跑了？如果是，先杀掉旧的 Timer
            if isfield(SatellitesHandles, key) && isfield(SatellitesHandles.(key), 'timer')
                oldTmr = SatellitesHandles.(key).timer;
                if isvalid(oldTmr)
                    stop(oldTmr);
                    delete(oldTmr);
                end
            end

            % 2. 这里的 GlobalFrame 是个全局变量，控制播放进度
            % 如果这是第一颗卫星，我们从第1帧开始
            % 如果已经有其他卫星在跑了，新卫星应该“追上”大家的进度吗？
            % 为了简单且稳妥（照顾新手），我们策略是：
            % 【每次添加新卫星，为了保证绝对同步，我们让所有卫星重置回第 1 帧】
            GlobalFrame = 1; 

            % 3. 创建并启动新的 Timer
            % 注意：Period 0.03秒 对应我们刚才设定的 dt=60秒
            % 意味着：现实中 0.03秒 = 仿真世界的 1分钟 (加速播放)
            tmr = timer('ExecutionMode','fixedRate', 'Period', 0.03, ...
                'TimerFcn', @(~,~)satelliteUpdate(app, key));
                
            SatellitesHandles.(key).timer = tmr;
            start(tmr);
            
            % 额外的小优化：
            % 既然我们重置了 GlobalFrame = 1，
            % 我们最好把其他正在跑的卫星的 Timer 也重启一下或者让它们自动对齐
            % 但在目前简单的架构下，只要 GlobalFrame 被重置，
            % 下一次所有卫星的 Timer 触发时，都会读到 GlobalFrame=2
            % 所以它们会自动对齐！不需要额外写代码。


        end

        % Button pushed function: PlotGroundTrackButton
        function PlotGroundTrackButtonPushed(app, event)
            global OE trackTime
            %%% Ground Track
            t = 1;
            dt = 1;
            for i = 1 : trackTime + 1
                t = t + dt;
                [OE(i+1,:)] = oblatenessEffects(OE(i,:));
                [r(i,1),nu(i,1)] = cal_Orbit_app(t,OE(i+1,:));
                [x(i,1),y(i,1),z(i,1)] = cal_position_app(t,OE(i+1,:));
                [lon(i,1),lat(i,1)] = groundTrack(t,x(i,1),y(i,1),z(i,1),nu(i,1),OE(i+1,:));
            end
            gx = geoaxes(app.GroundTrackPanel);
            geoscatter(gx,lat,lon-125,'.g');
            geobasemap(gx,'satellite');
        end

        % Value changed function: SatelliteDropdown
        function SatelliteDropdownValueChanged(app, event)
            selectedSatellite = app.SatelliteDropdown.Value;

            switch selectedSatellite
                case 'Satellite 1'
                    app.EpochtimeEditField.Value = 2.31e+04;%IGSO-1 (C06)
                    app.InclinationEditField.Value = 15.7;
                    app.RAANEditField.Value = 330.9;
                    app.EccentricityEditField.Value = 003878;
                    app.ArgumentofperigeeEditField.Value = 293.8;
                    app.MeananomalyEditField.Value = 261.7;
                    app.MeanmotionEditField.Value = 15.7;

                case 'Satellite 2'
                    app.EpochtimeEditField.Value = 2.31e+04;  % G4 (C04)
                    app.InclinationEditField.Value = 15.7 ;
                    app.RAANEditField.Value = 330.9;
                    app.EccentricityEditField.Value = 003878;
                    app.ArgumentofperigeeEditField.Value = 293.8;
                    app.MeananomalyEditField.Value = 261.7;
                    app.MeanmotionEditField.Value = 15.7;

                case 'Satellite 3'
                    app.EpochtimeEditField.Value =  2.31e+04;  % M3(C11)
                    app.InclinationEditField.Value = 55.9815;
                    app.RAANEditField.Value = 313.6994;
                    app.EccentricityEditField.Value = 0024307;
                    app.ArgumentofperigeeEditField.Value = 267.6726;
                    app.MeananomalyEditField.Value = 92.1113 ;
                    app.MeanmotionEditField.Value = 16;
            end
            
        end

        % Button pushed function: AddSatelliteButton
        function AddSatelliteButtonPushed(app, event)
            prompt = {'Enter Satellite Name:','Enter Epoch time:', 'Enter Inclination:', 'Enter RAAN:',...
                'Enter Eccentricity:','Enter Argument of perigee:','Enter Mean anomaly:','Enter Mean motion:'};
            dlgtitle = 'Add New Satellite';
            dims = [1 35];
            definput = {'NewSatellite', '21312', '15', '330','0.5','295','260','16'};
            answer = inputdlg(prompt, dlgtitle, dims, definput);
            if ~isempty(answer)
                satelliteName = answer{1};
                app.EpochtimeEditField.Value=str2num(answer{2});
                app.InclinationEditField.Value=str2num(answer{3});
                app.RAANEditField.Value=str2num(answer{4});
                app.EccentricityEditField.Value=str2num(answer{5});
                app.ArgumentofperigeeEditField.Value=str2num(answer{6});
                app.MeananomalyEditField.Value=str2num(answer{7});
                app.MeanmotionEditField.Value=str2num(answer{8});
                curname=app.SatelliteDropdown.Items;
                app.SatelliteDropdown.Items=[curname,{satelliteName}];
                app.SatelliteDropdown.Value=satelliteName;
            end

            app.LinkSatADropdown.Items = app.SatelliteDropdown.Items;
            app.LinkSatBDropdown.Items = app.SatelliteDropdown.Items;

        end

        % Button pushed function: DelButton
        function DelButtonPushed(app, event)
            % 获取当前下拉菜单的内容
            items = app.SatelliteDropdown.Items;

            % 获取当前选择的卫星
            selected = app.SatelliteDropdown.Value;

            % 找到要删除的卫星在 items 中的位置
            idx = strcmp(items, selected);

            % 如果找到了，就删除
            if any(idx)
                items(idx) = [];   % 删除该卫星
                app.SatelliteDropdown.Items = items;

                % 如果还有剩余的卫星，就默认选择第一个
                if ~isempty(items)
                    app.SatelliteDropdown.Value = items{1};
                else
                    app.SatelliteDropdown.Value = ''; % 没有卫星了
                end
            end
            app.LinkSatADropdown.Items = app.SatelliteDropdown.Items;
            app.LinkSatBDropdown.Items = app.SatelliteDropdown.Items;


        end

        % Button pushed function: DrawLinkButton
        function DrawLinkButtonPushed(app, event)
            
          
            global LinkTable SatellitesCache GlobalFrame

            satA = matlab.lang.makeValidName(app.LinkSatADropdown.Value);
            satB = matlab.lang.makeValidName(app.LinkSatBDropdown.Value);

            % ========= 1. 基本合法性检查 =========
            if strcmp(satA, satB)
                uialert(app.UIFigure,'不能选择同一颗卫星','Invalid Selection');
                return;
            end

            if ~isfield(SatellitesCache, satA) || ~isfield(SatellitesCache, satB)
                uialert(app.UIFigure,'所选卫星尚未绘制','Satellite Missing');
                return;
            end

            % ========= 2. 防止重复链路 =========
            for i = 1:numel(LinkTable)
                if (strcmp(LinkTable(i).satA,satA) && strcmp(LinkTable(i).satB,satB)) || ...
                        (strcmp(LinkTable(i).satA,satB) && strcmp(LinkTable(i).satB,satA))
                    return;
                end
            end

            % ========= 3. 创建链路句柄 =========
            ax = app.UIAxes2;
            h = plot3(ax,NaN,NaN,NaN,'g--','LineWidth',1.5);

            LinkTable(end+1) = struct( ...
                'satA',satA,...
                'satB',satB,...
                'handle',h);

            % ========= 4. 立刻更新一次 =========
            updateLinks();


        end

        % Button pushed function: DeleteLinkButton
        function DeleteLinkButtonPushed(app, event)
            
            global LinkTable

            if isempty(LinkTable)
                return;
            end

            satA = matlab.lang.makeValidName(app.LinkSatADropdown.Value);
            satB = matlab.lang.makeValidName(app.LinkSatBDropdown.Value);

            idx = [];

            % 找到对应链路
            for i = 1:numel(LinkTable)
                if (strcmp(LinkTable(i).satA,satA) && strcmp(LinkTable(i).satB,satB)) || ...
                        (strcmp(LinkTable(i).satA,satB) && strcmp(LinkTable(i).satB,satA))
                    idx = i;
                    break;
                end
            end

            if isempty(idx)
                return;   % 没这条链路，直接退出
            end

            % 删除图形句柄
            if isvalid(LinkTable(idx).handle)
                delete(LinkTable(idx).handle);
            end

            % 从表中移除
            LinkTable(idx) = [];


        end

        % Button pushed function: DrawSequenceLinkButton
        function DrawSequenceLinkButtonPushed(app, event)
           
            
                    % Button pushed function: DrawSequenceLinkButton
        
            
            global SatellitesCache LinkTable

            % ===== 1. 检查是否有卫星 =====
            % 如果缓存还没初始化或卫星太少，直接报错
            if isempty(SatellitesCache)
                uialert(app.UIFigure,'请先添加卫星并点击 Plot Orbit 绘制','错误');
                return;
            end

            names = fieldnames(SatellitesCache);
            N = numel(names);

            if N < 2
                uialert(app.UIFigure,'当前卫星数量不足，无法建立连接','错误');
                return;
            end

            
            % ===== 2. 读取并解析输入序列 (精确版逻辑) =====
            raw = strtrim(char(app.SequenceEditField.Value));
            
            % 之前的逻辑试图智能拆解 "123" -> 1,2,3，这在两位数卫星时会导致致命歧义
            % 所以现在我们废除“单字符串拆解”，强制要求使用分隔符
            
            % 使用正则表达式提取所有独立的数字块
            % \d+ 匹配连续的数字，例如 "1-3-24" 会被提取为 {'1', '3', '24'}
            tokens = regexp(raw, '\d+', 'match');
            
            if isempty(tokens)
                uialert(app.UIFigure,'请输入数字序列，使用横杠、逗号或空格分隔 (例如 1-3-24)','格式错误');
                return;
            end

            % 直接转换，不再尝试把 "1324" 拆成 1,3,2,4
            idx = str2double(tokens);

            % ===== 3. 序列长度检查 =====
            if numel(idx) < 2
                uialert(app.UIFigure,'序列长度至少为 2 (例如: 12 或 1-2)','错误');
                return;
            end

            % ===== 4. 索引合法性检查 =====
            if any(idx < 1) || any(idx > N)
                uialert(app.UIFigure,['索引超出范围！当前只有 ' num2str(N) ' 颗卫星。'],'错误');
                return;
            end

            % ===== 5. 逐段建立链路 =====
            % 遍历序列，两两配对建立连接
            count = 0;
            for k = 1:numel(idx)-1
                idxA = idx(k);
                idxB = idx(k+1);
                
                % 忽略指向自己的连接 (如 1-1)
                if idxA == idxB
                    continue;
                end

                satA = matlab.lang.makeValidName(names{idxA});
                satB = matlab.lang.makeValidName(names{idxB});

                % 防止重复链路检查
                duplicated = false;
                for i = 1:numel(LinkTable)
                    if (strcmp(LinkTable(i).satA,satA) && strcmp(LinkTable(i).satB,satB)) || ...
                            (strcmp(LinkTable(i).satA,satB) && strcmp(LinkTable(i).satB,satA))
                        duplicated = true;
                        break;
                    end
                end
                
                if duplicated
                    continue; 
                end

                % 这里的 UIAxes2 必须对应你 UI 界面上的 3D 地球坐标轴
                % 创建一个空的 plot3 对象作为占位符，updateLinks 会去更新它的数据
                h = plot3(app.UIAxes2, NaN, NaN, NaN, 'g--', 'LineWidth', 1.5);

                LinkTable(end+1) = struct( ...
                    'satA', satA, ...
                    'satB', satB, ...
                    'handle', h);
                
                count = count + 1;
            end

            % ===== 6. 立即刷新一次 =====
            % 只有成功建立了至少一条新链路才调用刷新
            if count > 0
                try
                    updateLinks(); % 确保 updateLinks.m 文件在路径中
                catch ME
                    uialert(app.UIFigure,['调用 updateLinks 失败: ' ME.message],'警告');
                end
            else
                % 如果没有报错但也没有建立新链路（可能是重复了）
                % 不做额外提示，体验会流畅些
            end


        end

        % Button pushed function: LoadTLEButton
        function LoadTLEButtonPushed(app, event)


            global mu Re D2R SatellitesCache SatellitesHandles SatelliteList LinkTable GlobalFrame
            
            % 必须先初始化这些物理常量，否则 addSatelliteOptimized 计算时会全是 NaN
            G = 6.67384e-11; Me = 5.9742e24;
            mu = G * Me;
            Re = 6371e3;
            D2R = pi/180;
            
            % 确保缓存结构体存在
            if isempty(SatellitesCache), SatellitesCache = struct(); end
            if isempty(SatellitesHandles), SatellitesHandles = struct(); end
            if isempty(SatelliteList), SatelliteList = {}; end
            if isempty(LinkTable), LinkTable = struct('satA',{},'satB',{},'handle',{}); end
            % ============================

          
            % 
            [file, path] = uigetfile('*.txt', '选择 TLE 数据文件');
            if isequal(file, 0), return; end

            % 1. 暂停中央定时器 (防止冲突)
            global MasterTimer
            if ~isempty(MasterTimer) && isvalid(MasterTimer)
                stop(MasterTimer);
            end

            filename = fullfile(path, file);
            fileID = fopen(filename, 'r');

            app.SatelliteDropdown.Items = {};
            count = 0;
            
            % 进度条
            d = uiprogressdlg(app.UIFigure, 'Title', '加载中', 'Message', '正在读取卫星数据...');

            while ~feof(fileID)
              
                line0 = fgetl(fileID); 
                if ~ischar(line0), break; end
                if isempty(strtrim(line0)), continue; end 

                line1 = fgetl(fileID); 
                line2 = fgetl(fileID); 

                if ~ischar(line1) || ~ischar(line2), break; end

                try
                    line1 = strtrim(line1);
                    parts = strsplit(strtrim(line2)); % 按照TLE的字段要求读数

                    satName = strtrim(line0);
                    inc      = str2double(line2(9:16));
                    raan     = str2double(line2(18:25));
                    eccStr   = line2(27:33);
                    ecc      = str2double(['0.', eccStr]);
                    argP     = str2double(line2(35:42));
                    meanAnom = str2double(line2(44:51));
                    meanMotion = str2double(line2(53:63));
                    epoch    = str2double(line1(19:32));

                    if isnan(inc) || isnan(raan)
                        error('数据解析为非数字');
                    end

                   
                    % 调用优化的添加函数
                    safeName = matlab.lang.makeValidName(satName);
                    
                    addSatelliteOptimized(app, safeName, epoch, inc, raan, ecc, argP, meanAnom, meanMotion);

                    app.SatelliteDropdown.Items{end+1} = satName;
                    count = count + 1;
                    
                catch ME
                    % 忽略错误，继续下一颗
                    continue;
                end
            end
            
            app.LinkSatADropdown.Items = app.SatelliteDropdown.Items;
            app.LinkSatBDropdown.Items = app.SatelliteDropdown.Items;
            fclose(fileID);
            close(d);
            
            % 2. 启动中央定时器
            global GlobalFrame
            GlobalFrame = 1;
            if isempty(MasterTimer) || ~isvalid(MasterTimer)
                MasterTimer = timer('ExecutionMode','fixedRate', ...
                    'Period', 0.05, ... 
                    'TimerFcn', @(~,~)GlobalUpdateManager(app));
            end
            start(MasterTimer);
            
            uialert(app.UIFigure, ['成功加载 ', num2str(count), ' 颗卫星。'], '完成');

        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnterTLEdataPanel = uipanel(app.UIFigure);
            app.EnterTLEdataPanel.Title = 'Enter TLE data';
            app.EpochtimeEditFieldLabel = uilabel(app.EnterTLEdataPanel);
            app.EpochtimeEditFieldLabel.Text = 'Epoch time';
            app.EpochtimeEditField = uieditfield(app.EnterTLEdataPanel, 'numeric');
            app.EpochtimeEditField.Value = 21310;
            app.InclinationEditFieldLabel = uilabel(app.EnterTLEdataPanel);
            app.InclinationEditFieldLabel.Text = 'Inclination';
            app.InclinationEditField = uieditfield(app.EnterTLEdataPanel, 'numeric');
            app.InclinationEditField.Value = 15.7;
            app.RAANEditFieldLabel = uilabel(app.EnterTLEdataPanel);
            app.RAANEditFieldLabel.Text = 'RAAN';
            app.RAANEditField = uieditfield(app.EnterTLEdataPanel, 'numeric');
            app.RAANEditField.Value = 330.9;
            app.EccentricityEditFieldLabel = uilabel(app.EnterTLEdataPanel);
            app.EccentricityEditFieldLabel.Text = 'Eccentricity';
            app.EccentricityEditField = uieditfield(app.EnterTLEdataPanel, 'numeric');
            app.EccentricityEditField.Value = 3878;
            app.ArgumentofperigeeEditFieldLabel = uilabel(app.EnterTLEdataPanel);
            app.ArgumentofperigeeEditFieldLabel.Text = 'Argument of perigee';
            app.ArgumentofperigeeEditField = uieditfield(app.EnterTLEdataPanel, 'numeric');
            app.ArgumentofperigeeEditField.Value = 293.8;
            app.MeananomalyEditFieldLabel = uilabel(app.EnterTLEdataPanel);
            app.MeananomalyEditFieldLabel.Text = 'Mean anomaly';
            app.MeananomalyEditField = uieditfield(app.EnterTLEdataPanel, 'numeric');
            app.MeananomalyEditField.Value = 261.7;
            app.MeanmotionEditFieldLabel = uilabel(app.EnterTLEdataPanel);
            app.MeanmotionEditFieldLabel.Text = 'Mean motion';
            app.MeanmotionEditField = uieditfield(app.EnterTLEdataPanel, 'numeric');
            app.MeanmotionEditField.Value = 15.7;
            app.TextArea = uitextarea(app.EnterTLEdataPanel);
            app.UploadDataButton = uibutton(app.EnterTLEdataPanel, 'push');
            app.UploadDataButton.ButtonPushedFcn = createCallbackFcn(app, @UploadDataButtonPushed, true);
            app.UploadDataButton.Text = 'Upload Data';
            app.PlotOrbitButton = uibutton(app.EnterTLEdataPanel, 'push');
            app.PlotOrbitButton.ButtonPushedFcn = createCallbackFcn(app, @PlotOrbitButtonPushed, true);
            app.PlotOrbitButton.Text = 'Plot Orbit';
            app.SatelliteLabel = uilabel(app.EnterTLEdataPanel);
            app.SatelliteLabel.Text = 'Satellite';
            app.SatelliteDropdown = uidropdown(app.EnterTLEdataPanel);
            app.SatelliteDropdown.Items = {'Satellite 1', 'Satellite 2', 'Satellite 3'};
            app.SatelliteDropdown.ValueChangedFcn = createCallbackFcn(app, @SatelliteDropdownValueChanged, true);
            app.SatelliteDropdown.Value = 'Satellite 1';
            app.AddSatelliteButton = uibutton(app.EnterTLEdataPanel, 'push');
            app.AddSatelliteButton.ButtonPushedFcn = createCallbackFcn(app, @AddSatelliteButtonPushed, true);
            app.AddSatelliteButton.Text = 'Add';
            app.DelButton = uibutton(app.EnterTLEdataPanel, 'push');
            app.DelButton.ButtonPushedFcn = createCallbackFcn(app, @DelButtonPushed, true);
            app.DelButton.Text = 'Del';
            app.SatellitesPositionattheepochtimePanel = uipanel(app.UIFigure);
            app.SatellitesPositionattheepochtimePanel.Title = 'Satellite''s Position (at the epoch time)';
            app.UIAxes2 = uiaxes(app.SatellitesPositionattheepochtimePanel);
            app.TrajectoryontheOibitalPlanePanel = uipanel(app.UIFigure);
            app.TrajectoryontheOibitalPlanePanel.Title = 'Trajectory on the Oibital Plane';
            app.Label = uilabel(app.TrajectoryontheOibitalPlanePanel);
            app.Label.Text = 'LinkSatADropdown';
            app.LinkSatADropdown = uidropdown(app.TrajectoryontheOibitalPlanePanel);
            app.LinkSatADropdown.Items = {'Satellite 1', 'Satellite 2', 'Satellite 3'};
            app.LinkSatADropdown.Value = 'Satellite 1';
            app.LinkSatBDropdownDropDownLabel = uilabel(app.TrajectoryontheOibitalPlanePanel);
            app.LinkSatBDropdownDropDownLabel.Text = 'LinkSatBDropdown';
            app.LinkSatBDropdown = uidropdown(app.TrajectoryontheOibitalPlanePanel);
            app.LinkSatBDropdown.Items = {'Satellite 1', 'Satellite 2', 'Satellite 3'};
            app.LinkSatBDropdown.Value = 'Satellite 1';
            app.DrawLinkButton = uibutton(app.TrajectoryontheOibitalPlanePanel, 'push');
            app.DrawLinkButton.ButtonPushedFcn = createCallbackFcn(app, @DrawLinkButtonPushed, true);
            app.DrawLinkButton.Text = 'DrawLinkButton';
            app.DeleteLinkButton = uibutton(app.TrajectoryontheOibitalPlanePanel, 'push');
            app.DeleteLinkButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLinkButtonPushed, true);
            app.DeleteLinkButton.Text = 'DeleteLinkButton';
            app.Label_2 = uilabel(app.TrajectoryontheOibitalPlanePanel);
            app.Label_2.Text = 'SequenceEditField';
            app.SequenceEditField = uieditfield(app.TrajectoryontheOibitalPlanePanel, 'text');
            app.DrawSequenceLinkButton = uibutton(app.TrajectoryontheOibitalPlanePanel, 'push');
            app.DrawSequenceLinkButton.ButtonPushedFcn = createCallbackFcn(app, @DrawSequenceLinkButtonPushed, true);
            app.DrawSequenceLinkButton.Text = 'DrawSequenceLink';
            app.LoadTLEButton = uibutton(app.TrajectoryontheOibitalPlanePanel, 'push');
            app.LoadTLEButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTLEButtonPushed, true);
            app.LoadTLEButton.Text = 'LoadTLE';
            app.SatelliteOrbitTrackerLabel = uilabel(app.UIFigure);
            app.SatelliteOrbitTrackerLabel.Text = '< Satellite Orbit Tracker >';
            app.madebyYoonjoongKimLabel = uilabel(app.UIFigure);
            app.madebyYoonjoongKimLabel.Text = 'made by. Yoonjoong Kim';
            app.GroundTrackPanel = uipanel(app.UIFigure);
            app.GroundTrackPanel.Title = 'Ground Track';
            app.SettimerangeButtonGroup = uibuttongroup(app.UIFigure);
            app.SettimerangeButtonGroup.Title = ' Set time range';
            app.CyclesButton = uiradiobutton(app.SettimerangeButtonGroup);
            app.CyclesButton.Text = 'Cycles';
            app.CyclesButton.Value = true;
            app.Spinner = uispinner(app.SettimerangeButtonGroup);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.PlotGroundTrackButton = uibutton(app.SettimerangeButtonGroup, 'push');
            app.PlotGroundTrackButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGroundTrackButtonPushed, true);
            app.PlotGroundTrackButton.Text = 'Plot Ground Track';
            app.SecondsButton = uiradiobutton(app.SettimerangeButtonGroup);
            app.SecondsButton.Text = 'Seconds';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SatelliteOrbitApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end